classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        EStopSwitch             matlab.ui.control.Switch
        TargetStatusGauge       matlab.ui.control.LinearGauge
        TargetStatusGaugeLabel  matlab.ui.control.Label
        EStopLamp               matlab.ui.control.Lamp
        EStopLampLabel          matlab.ui.control.Label
        MoveHeadRightButton     matlab.ui.control.StateButton
        MoveHeadLeftButton      matlab.ui.control.StateButton
        MoveHeadForwardButton   matlab.ui.control.StateButton
        MoveHeadDownButton      matlab.ui.control.StateButton
        MoveHeadBackButton      matlab.ui.control.StateButton
        MoveHeadUpButton        matlab.ui.control.StateButton
        PredefinedImagesLabel   matlab.ui.control.Label
        DropDown                matlab.ui.control.DropDown
        LoadButton              matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function updateimage(app,imagefile)
            
            % For corn.tif, read the second image in the file
            if strcmp(imagefile,'corn.tif')
                im = imread('corn.tif', 2);
            else
                try
                    im = imread(imagefile);
                catch ME
                    % If problem reading image, display error message
                    uialert(app.UIFigure, ME.message, 'Image Error');
                    return;
                end            
            end 
            
            % Create histograms based on number of color channels
            switch size(im,3)
                case 1
                    % Display the grayscale image
                    imagesc(app.ImageAxes,im);
                    
                    % Plot all histograms with the same data for grayscale
                    histr = histogram(app.RedAxes, im, 'FaceColor',[1 0 0],'EdgeColor', 'none');
                    histg = histogram(app.GreenAxes, im, 'FaceColor',[0 1 0],'EdgeColor', 'none');
                    histb = histogram(app.BlueAxes, im, 'FaceColor',[0 0 1],'EdgeColor', 'none');
                    
                case 3
                    % Display the truecolor image
                    imagesc(app.ImageAxes,im);
                    
                    % Plot the histograms
                    histr = histogram(app.RedAxes, im(:,:,1), 'FaceColor', [1 0 0], 'EdgeColor', 'none');
                    histg = histogram(app.GreenAxes, im(:,:,2), 'FaceColor', [0 1 0], 'EdgeColor', 'none');
                    histb = histogram(app.BlueAxes, im(:,:,3), 'FaceColor', [0 0 1], 'EdgeColor', 'none');
                    
                otherwise
                    % Error when image is not grayscale or truecolor
                    uialert(app.UIFigure, 'Image must be grayscale or truecolor.', 'Image Error');
                    return;
            end
                % Get largest bin count
                maxr = max(histr.BinCounts);
                maxg = max(histg.BinCounts);
                maxb = max(histb.BinCounts);
                maxcount = max([maxr maxg maxb]);
                
                % Set y axes limits based on largest bin count
                app.RedAxes.YLim = [0 maxcount];
                app.RedAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
                app.GreenAxes.YLim = [0 maxcount];
                app.GreenAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
                app.BlueAxes.YLim = [0 maxcount];
                app.BlueAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
         
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Configure image axes
            app.ImageAxes.Visible = 'off';
            app.ImageAxes.Colormap = gray(256);
            axis(app.ImageAxes, 'image');
            
            % Update the image and histograms
            updateimage(app, 'peppers.png');
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            
            % Update the image and histograms
            updateimage(app, app.DropDown.Value);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            
            sim = RoboTargeterSimulation();
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               updateimage(app, fname);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Image Histograms';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Workspace Simulaton')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Run Simulation';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Automatic', 'Manual Laser', 'Manual Target', ''};
            app.DropDown.ItemsData = {'peppers.png', 'corn.tif'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'peppers.png';
            app.PredefinedImagesLabel = uilabel(app.UIFigure);
            app.PredefinedImagesLabel.Text = {'Hide and Seek'; ''};
            app.MoveHeadUpButton = uibutton(app.UIFigure, 'state');
            app.MoveHeadUpButton.Text = 'Move Head Up';
            app.MoveHeadBackButton = uibutton(app.UIFigure, 'state');
            app.MoveHeadBackButton.Text = 'Move Head Back';
            app.MoveHeadDownButton = uibutton(app.UIFigure, 'state');
            app.MoveHeadDownButton.Text = 'Move Head Down';
            app.MoveHeadForwardButton = uibutton(app.UIFigure, 'state');
            app.MoveHeadForwardButton.Text = 'Move Head Forward';
            app.MoveHeadLeftButton = uibutton(app.UIFigure, 'state');
            app.MoveHeadLeftButton.Text = 'Move Head Left';
            app.MoveHeadRightButton = uibutton(app.UIFigure, 'state');
            app.MoveHeadRightButton.Text = 'Move Head Right';
            app.EStopLampLabel = uilabel(app.UIFigure);
            app.EStopLampLabel.Text = 'E Stop';
            app.EStopLamp = uilamp(app.UIFigure);
            app.TargetStatusGaugeLabel = uilabel(app.UIFigure);
            app.TargetStatusGaugeLabel.Text = 'Target Status';
            app.TargetStatusGauge = uigauge(app.UIFigure, 'linear');
            app.EStopSwitch = uiswitch(app.UIFigure, 'slider');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end