classdef GUITesting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ResumeButton                matlab.ui.control.Button
        ESTOPLamp                   matlab.ui.control.Lamp
        EmergencyStop               matlab.ui.control.Switch
        EMERGENCYSTOPLabel          matlab.ui.control.Label
        SimulationProgramPanel      matlab.ui.container.Panel
        MoveDennis                  matlab.ui.control.Button
        TargetbotSetRandomTargetLocationButton  matlab.ui.control.Button
        LaserbotFindTargetButton    matlab.ui.control.Button
        RemoveObstacleButton        matlab.ui.control.Button
        CreateObstacleButton        matlab.ui.control.Button
        RemoveHazardSignButton      matlab.ui.control.Button
        CreateHazardSignButton      matlab.ui.control.Button
        RandomTargetPracticeButton  matlab.ui.control.Button
        InitialiseButton            matlab.ui.control.Button
        ManualMovementPanel         matlab.ui.container.Panel
        RobotDropDown               matlab.ui.control.DropDown
        RobotDropDownLabel          matlab.ui.control.Label
        pJ6Button                   matlab.ui.control.Button
        pJ5Button                   matlab.ui.control.Button
        pJ4Button                   matlab.ui.control.Button
        pJ3Button                   matlab.ui.control.Button
        pJ2Button                   matlab.ui.control.Button
        pJ1Button                   matlab.ui.control.Button
        nJ6Button                   matlab.ui.control.Button
        nJ5Button                   matlab.ui.control.Button
        nJ4Button                   matlab.ui.control.Button
        nJ3Button                   matlab.ui.control.Button
        nJ2Button                   matlab.ui.control.Button
        nJ1Button                   matlab.ui.control.Button
        J6                          matlab.ui.control.Label
        J5                          matlab.ui.control.Label
        J4                          matlab.ui.control.Label
        J3                          matlab.ui.control.Label
        J2                          matlab.ui.control.Label
        J1                          matlab.ui.control.Label
        Joint6Gauge                 matlab.ui.control.LinearGauge
        Joint6Label                 matlab.ui.control.Label
        Joint5Gauge                 matlab.ui.control.LinearGauge
        Joint5Label                 matlab.ui.control.Label
        Joint4Gauge                 matlab.ui.control.LinearGauge
        Joint4Label                 matlab.ui.control.Label
        Joint3Gauge                 matlab.ui.control.LinearGauge
        Joint3Label                 matlab.ui.control.Label
        Joint2Gauge                 matlab.ui.control.LinearGauge
        Joint2Label                 matlab.ui.control.Label
        Joint1Gauge                 matlab.ui.control.LinearGauge
        Joint1Label                 matlab.ui.control.Label
        YAWLabel                    matlab.ui.control.Label
        PITCHLabel                  matlab.ui.control.Label
        ROLLLabel                   matlab.ui.control.Label
        pROLLButton                 matlab.ui.control.Button
        nYAWButton                  matlab.ui.control.Button
        pPITCHButton                matlab.ui.control.Button
        pYAWButton                  matlab.ui.control.Button
        nROLLButton                 matlab.ui.control.Button
        nPITCHButton                matlab.ui.control.Button
        pZButton                    matlab.ui.control.Button
        nZButton                    matlab.ui.control.Button
        pXButton                    matlab.ui.control.Button
        nYButton                    matlab.ui.control.Button
        pYButton                    matlab.ui.control.Button
        nXButton                    matlab.ui.control.Button
        UICameraView                matlab.ui.control.UIAxes
        UISimulationView            matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        sim; % Simulation to be accessed by the app
        robotControl = 1; % Current robot being manipulated
        hazard;             % Simulation hazard sign
    end

    methods (Access = public)

        function UpdateUI(app)
            % UpdateUI - Updates the joint values and linear gauges

            % Get current joint state
            if app.robotControl == 1
                q = app.sim.laserBot.model.getpos()*180/pi;
            elseif app.robotControl == 2
                q = app.sim.targetBot.model.getpos()*180/pi;
            end

            % Update values on GUI
            app.J1.Text = num2str(q(1));
            app.J2.Text = num2str(q(2));
            app.J3.Text = num2str(q(3));
            app.J4.Text = num2str(q(4));
            app.J5.Text = num2str(q(5));
            app.J6.Text = num2str(q(6));

            app.Joint1Gauge.Value = q(1);
            app.Joint2Gauge.Value = q(2);
            app.Joint3Gauge.Value = q(3);
            app.Joint4Gauge.Value = q(4);
            app.Joint5Gauge.Value = q(5);
            app.Joint6Gauge.Value = q(6);

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InitialiseButton
        function InitialiseButtonPushed(app, event)
            app.sim = RoboTargeterSimulation(app);
            app.UpdateUI();
        end

        % Button pushed function: pZButton
        function pZButtonPushed(app, event)
            input = [0, 0, 1, 0, 0, 0]; % Input for BotSet controller in UR3
            if app.robotControl == 1
                app.sim.laserBot.BotSet(app,input);
            elseif app.robotControl == 2
                app.sim.targetBot.BotSet(app,input);
            end
            app.UpdateUI();
        end

        % Button pushed function: nZButton
        function nZButtonPushed(app, event)
            input = [0, 0, -1, 0, 0, 0]; % Input for BotSet controller in UR3
            if app.robotControl == 1
                app.sim.laserBot.BotSet(app,input);
            elseif app.robotControl == 2
                app.sim.targetBot.BotSet(app,input);
            end
            app.UpdateUI();
        end

        % Button pushed function: pXButton
        function pXButtonPushed(app, event)
            input = [1, 0, 0, 0, 0, 0]; % Input for BotSet controller in UR3
            if app.robotControl == 1
                app.sim.laserBot.BotSet(app,input);
            elseif app.robotControl == 2
                app.sim.targetBot.BotSet(app,input);
            end
            app.UpdateUI();
        end

        % Button pushed function: nXButton
        function nXButtonPushed(app, event)
            input = [-1, 0, 0, 0, 0, 0]; % Input for BotSet controller in UR3
            if app.robotControl == 1
                app.sim.laserBot.BotSet(app,input);
            elseif app.robotControl == 2
                app.sim.targetBot.BotSet(app,input);
            end
            app.UpdateUI();
        end

        % Button pushed function: pYButton
        function pYButtonPushed(app, event)
            input = [0, 1, 0, 0, 0, 0]; % Input for BotSet controller in UR3
            if app.robotControl == 1
                app.sim.laserBot.BotSet(app,input);
            elseif app.robotControl == 2
                app.sim.targetBot.BotSet(app,input);
            end
            app.UpdateUI();
        end

        % Button pushed function: nYButton
        function nYButtonPushed(app, event)
            input = [0, -1, 0, 0, 0, 0]; % Input for BotSet controller in UR3
            if app.robotControl == 1
                app.sim.laserBot.BotSet(app,input);
            elseif app.robotControl == 2
                app.sim.targetBot.BotSet(app,input);
            end
            app.UpdateUI();
        end

        % Button pushed function: nROLLButton
        function nROLLButtonPushed(app, event)
            input = [0, 0, 0, -1, 0, 0]; % Input for BotSet controller in UR3
            if app.robotControl == 1
                app.sim.laserBot.BotSet(app,input);
            elseif app.robotControl == 2
                app.sim.targetBot.BotSet(app,input);
            end
            app.UpdateUI();
        end

        % Button pushed function: pROLLButton
        function pROLLButtonPushed(app, event)
            input = [0, 0, 0, 1, 0, 0]; % Input for BotSet controller in UR3
            if app.robotControl == 1
                app.sim.laserBot.BotSet(app,input);
            elseif app.robotControl == 2
                app.sim.targetBot.BotSet(app,input);
            end
            app.UpdateUI();
        end

        % Button pushed function: nPITCHButton
        function nPITCHButtonPushed(app, event)
            input = [0, 0, 0, 0, -1, 0]; % Input for BotSet controller in UR3
            if app.robotControl == 1
                app.sim.laserBot.BotSet(app,input);
            elseif app.robotControl == 2
                app.sim.targetBot.BotSet(app,input);
            end
            app.UpdateUI();
        end

        % Button pushed function: pPITCHButton
        function pPITCHButtonPushed(app, event)
            input = [0, 0, 0, 0, 1, 0]; % Input for BotSet controller in UR3
            if app.robotControl == 1
                app.sim.laserBot.BotSet(app,input);
            elseif app.robotControl == 2
                app.sim.targetBot.BotSet(app,input);
            end
            app.UpdateUI();
        end

        % Button pushed function: nYAWButton
        function nYAWButtonPushed(app, event)
            input = [0, 0, 0, 0, 0, -1]; % Input for BotSet controller in UR3
            if app.robotControl == 1
                app.sim.laserBot.BotSet(app,input);
            elseif app.robotControl == 2
                app.sim.targetBot.BotSet(app,input);
            end
            app.UpdateUI();
        end

        % Button pushed function: pYAWButton
        function pYAWButtonPushed(app, event)
            input = [0, 0, 0, 0, 0, 1]; % Input for BotSet controller in UR3
            if app.robotControl == 1
                app.sim.laserBot.BotSet(app,input);
            elseif app.robotControl == 2
                app.sim.targetBot.BotSet(app,input);
            end
            app.UpdateUI();
        end

        % Button pushed function: nJ1Button
        function nJ1ButtonPushed(app, event)
            input = [-1, 0, 0, 0, 0, 0]; % Input for JointSet controller in UR3
            if app.robotControl == 1
                app.sim.laserBot.JointSet(app,input);
            elseif app.robotControl == 2
                app.sim.targetBot.JointSet(app,input);
            end
            app.UpdateUI();
        end

        % Button pushed function: nJ2Button
        function nJ2ButtonPushed(app, event)
            input = [0, -1, 0, 0, 0, 0]; % Input for JointSet controller in UR3
            if app.robotControl == 1
                app.sim.laserBot.JointSet(app,input);
            elseif app.robotControl == 2
                app.sim.targetBot.JointSet(app,input);
            end
            app.UpdateUI();
        end

        % Button pushed function: nJ3Button
        function nJ3ButtonPushed(app, event)
            input = [0, 0, -1, 0, 0, 0]; % Input for JointSet controller in UR3
            if app.robotControl == 1
                app.sim.laserBot.JointSet(app,input);
            elseif app.robotControl == 2
                app.sim.targetBot.JointSet(app,input);
            end
            app.UpdateUI();
        end

        % Button pushed function: nJ4Button
        function nJ4ButtonPushed(app, event)
            input = [0, 0, 0, -1, 0, 0]; % Input for JointSet controller in UR3
            if app.robotControl == 1
                app.sim.laserBot.JointSet(app,input);
            elseif app.robotControl == 2
                app.sim.targetBot.JointSet(app,input);
            end
            app.UpdateUI();
        end

        % Button pushed function: nJ5Button
        function nJ5ButtonPushed(app, event)
            input = [0, 0, 0, 0, -1, 0]; % Input for JointSet controller in UR3
            if app.robotControl == 1
                app.sim.laserBot.JointSet(app,input);
            elseif app.robotControl == 2
                app.sim.targetBot.JointSet(app,input);
            end
            app.UpdateUI();
        end

        % Button pushed function: nJ6Button
        function nJ6ButtonPushed(app, event)
            input = [0, 0, 0, 0, 0, -1]; % Input for JointSet controller in UR3
            if app.robotControl == 1
                app.sim.laserBot.JointSet(app,input);
            elseif app.robotControl == 2
                app.sim.targetBot.JointSet(app,input);
            end
            app.UpdateUI();
        end

        % Button pushed function: pJ1Button
        function pJ1ButtonPushed(app, event)
            input = [1, 0, 0, 0, 0, 0]; % Input for JointSet controller in UR3
            if app.robotControl == 1
                app.sim.laserBot.JointSet(app,input);
            elseif app.robotControl == 2
                app.sim.targetBot.JointSet(app,input);
            end
            app.UpdateUI();
        end

        % Button pushed function: pJ2Button
        function pJ2ButtonPushed(app, event)
            input = [0, 1, 0, 0, 0, 0]; % Input for JointSet controller in UR3
            if app.robotControl == 1
                app.sim.laserBot.JointSet(app,input);
            elseif app.robotControl == 2
                app.sim.targetBot.JointSet(app,input);
            end
            app.UpdateUI();
        end

        % Button pushed function: pJ3Button
        function pJ3ButtonPushed(app, event)
            input = [0, 0, 1, 0, 0, 0]; % Input for JointSet controller in UR3
            if app.robotControl == 1
                app.sim.laserBot.JointSet(app,input);
            elseif app.robotControl == 2
                app.sim.targetBot.JointSet(app,input);
            end
            app.UpdateUI();
        end

        % Button pushed function: pJ4Button
        function pJ4ButtonPushed(app, event)
            input = [0, 0, 0, 1, 0, 0]; % Input for JointSet controller in UR3
            if app.robotControl == 1
                app.sim.laserBot.JointSet(app,input);
            elseif app.robotControl == 2
                app.sim.targetBot.JointSet(app,input);
            end
            app.UpdateUI();
        end

        % Button pushed function: pJ5Button
        function pJ5ButtonPushed(app, event)
            input = [0, 0, 0, 0, 1, 0]; % Input for JointSet controller in UR3
            if app.robotControl == 1
                app.sim.laserBot.JointSet(app,input);
            elseif app.robotControl == 2
                app.sim.targetBot.JointSet(app,input);
            end
            app.UpdateUI();
        end

        % Button pushed function: pJ6Button
        function pJ6ButtonPushed(app, event)
            input = [0, 0, 0, 0, 0, 1]; % Input for JointSet controller in UR3
            if app.robotControl == 1
                app.sim.laserBot.JointSet(app,input);
            elseif app.robotControl == 2
                app.sim.targetBot.JointSet(app,input);
            end
            app.UpdateUI();
        end

        % Button pushed function: RandomTargetPracticeButton
        function RandomTargetPracticeButtonPushed(app, event)
            app.sim.RandomTargetPractice();
        end

        % Value changed function: RobotDropDown
        function RobotDropDownValueChanged(app, event)
            value = app.RobotDropDown.Value;
            if strcmpi(value, 'Laserbot')
                app.robotControl = 1;
            elseif strcmpi(value, 'Targetbot')
                app.robotControl = 2;
            end
            app.UpdateUI();
        end

        % Button pushed function: CreateHazardSignButton
        function CreateHazardSignButtonPushed(app, event)
            app.sim.CreateHazard();
        end

        % Button pushed function: RemoveHazardSignButton
        function RemoveHazardSignButtonPushed(app, event)
            app.sim.RemoveHazard();
        end

        % Button pushed function: LaserbotFindTargetButton
        function LaserbotFindTargetButtonPushed(app, event)
            app.sim.laserBot.FindTarget();
        end

        % Button pushed function: TargetbotSetRandomTargetLocationButton
        function TargetbotSetRandomTargetLocationButtonPushed(app, event)
            app.sim.targetBot.SetRandomTarget();
        end

        % Value changed function: EmergencyStop
        function EmergencyStopValueChanged(app, event)

            while strcmpi(app.EmergencyStop.Value, 'On')
                display('EMERGENCY STOP INITIATED')
                app.ESTOPLamp.Color = 'Red';
                pause(1);
            end
            app.ESTOPLamp.Color = 'Green';
            set(app.ResumeButton, 'UserData', 'edit')
            waitfor(app.ResumeButton, 'UserData', 'read')
        end

        % Button pushed function: ResumeButton
        function ResumeButtonPushed(app, event)

        end

        % Button pushed function: CreateObstacleButton
        function CreateObstacleButtonPushed(app, event)
            app.sim.CreateObstacle();
        end

        % Button pushed function: RemoveObstacleButton
        function RemoveObstacleButtonPushed(app, event)
            app.sim.RemoveObstacle();
        end

        % Button pushed function: MoveDennis
        function MoveDennisButtonPushed(app, event)
            app.sim.MoveDennis();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UISimulationView = uiaxes(app.UIFigure);
            title(app.UISimulationView, 'Simulation View')
            xlabel(app.UISimulationView, 'X')
            ylabel(app.UISimulationView, 'Y')
            zlabel(app.UISimulationView, 'Z')
            app.UICameraView = uiaxes(app.UIFigure);
            title(app.UICameraView, 'Camera View')
            xlabel(app.UICameraView, 'X')
            ylabel(app.UICameraView, 'Y')
            zlabel(app.UICameraView, 'Z')
            app.ManualMovementPanel = uipanel(app.UIFigure);
            app.ManualMovementPanel.Title = 'Manual Movement';
            app.nXButton = uibutton(app.ManualMovementPanel, 'push');
            app.nXButton.ButtonPushedFcn = createCallbackFcn(app, @nXButtonPushed, true);
            app.nXButton.Text = '-X';
            app.pYButton = uibutton(app.ManualMovementPanel, 'push');
            app.pYButton.ButtonPushedFcn = createCallbackFcn(app, @pYButtonPushed, true);
            app.pYButton.Text = '+Y';
            app.nYButton = uibutton(app.ManualMovementPanel, 'push');
            app.nYButton.ButtonPushedFcn = createCallbackFcn(app, @nYButtonPushed, true);
            app.nYButton.Text = '-Y';
            app.pXButton = uibutton(app.ManualMovementPanel, 'push');
            app.pXButton.ButtonPushedFcn = createCallbackFcn(app, @pXButtonPushed, true);
            app.pXButton.Text = '+X';
            app.nZButton = uibutton(app.ManualMovementPanel, 'push');
            app.nZButton.ButtonPushedFcn = createCallbackFcn(app, @nZButtonPushed, true);
            app.nZButton.Text = '-Z';
            app.pZButton = uibutton(app.ManualMovementPanel, 'push');
            app.pZButton.ButtonPushedFcn = createCallbackFcn(app, @pZButtonPushed, true);
            app.pZButton.Text = '+Z';
            app.nPITCHButton = uibutton(app.ManualMovementPanel, 'push');
            app.nPITCHButton.ButtonPushedFcn = createCallbackFcn(app, @nPITCHButtonPushed, true);
            app.nPITCHButton.Text = '-';
            app.nROLLButton = uibutton(app.ManualMovementPanel, 'push');
            app.nROLLButton.ButtonPushedFcn = createCallbackFcn(app, @nROLLButtonPushed, true);
            app.nROLLButton.Text = '-';
            app.pYAWButton = uibutton(app.ManualMovementPanel, 'push');
            app.pYAWButton.ButtonPushedFcn = createCallbackFcn(app, @pYAWButtonPushed, true);
            app.pYAWButton.Text = '+';
            app.pPITCHButton = uibutton(app.ManualMovementPanel, 'push');
            app.pPITCHButton.ButtonPushedFcn = createCallbackFcn(app, @pPITCHButtonPushed, true);
            app.pPITCHButton.Text = '+';
            app.nYAWButton = uibutton(app.ManualMovementPanel, 'push');
            app.nYAWButton.ButtonPushedFcn = createCallbackFcn(app, @nYAWButtonPushed, true);
            app.nYAWButton.Text = '-';
            app.pROLLButton = uibutton(app.ManualMovementPanel, 'push');
            app.pROLLButton.ButtonPushedFcn = createCallbackFcn(app, @pROLLButtonPushed, true);
            app.pROLLButton.Text = '+';
            app.ROLLLabel = uilabel(app.ManualMovementPanel);
            app.ROLLLabel.Text = 'ROLL';
            app.PITCHLabel = uilabel(app.ManualMovementPanel);
            app.PITCHLabel.Text = 'PITCH';
            app.YAWLabel = uilabel(app.ManualMovementPanel);
            app.YAWLabel.Text = 'YAW';
            app.Joint1Label = uilabel(app.ManualMovementPanel);
            app.Joint1Label.Text = 'Joint 1:';
            app.Joint1Gauge = uigauge(app.ManualMovementPanel, 'linear');
            app.Joint1Gauge.Limits = [-360 360];
            app.Joint1Gauge.MajorTicks = [-360 0 360];
            app.Joint2Label = uilabel(app.ManualMovementPanel);
            app.Joint2Label.Text = 'Joint 2:';
            app.Joint2Gauge = uigauge(app.ManualMovementPanel, 'linear');
            app.Joint2Gauge.Limits = [-90 90];
            app.Joint2Gauge.MajorTicks = [-90 0 90];
            app.Joint3Label = uilabel(app.ManualMovementPanel);
            app.Joint3Label.Text = 'Joint 3:';
            app.Joint3Gauge = uigauge(app.ManualMovementPanel, 'linear');
            app.Joint3Gauge.Limits = [-170 170];
            app.Joint3Gauge.MajorTicks = [-170 0 170];
            app.Joint4Label = uilabel(app.ManualMovementPanel);
            app.Joint4Label.Text = 'Joint 4:';
            app.Joint4Gauge = uigauge(app.ManualMovementPanel, 'linear');
            app.Joint4Gauge.Limits = [-360 360];
            app.Joint4Gauge.MajorTicks = [-360 0 360];
            app.Joint5Label = uilabel(app.ManualMovementPanel);
            app.Joint5Label.Text = 'Joint 5:';
            app.Joint5Gauge = uigauge(app.ManualMovementPanel, 'linear');
            app.Joint5Gauge.Limits = [-360 360];
            app.Joint5Gauge.MajorTicks = [-360 0 360];
            app.Joint6Label = uilabel(app.ManualMovementPanel);
            app.Joint6Label.Text = 'Joint 6:';
            app.Joint6Gauge = uigauge(app.ManualMovementPanel, 'linear');
            app.Joint6Gauge.Limits = [-360 360];
            app.Joint6Gauge.MajorTicks = [-360 0 360];
            app.J1 = uilabel(app.ManualMovementPanel);
            app.J1.Text = '0';
            app.J2 = uilabel(app.ManualMovementPanel);
            app.J2.Text = '0';
            app.J3 = uilabel(app.ManualMovementPanel);
            app.J3.Text = '0';
            app.J4 = uilabel(app.ManualMovementPanel);
            app.J4.Text = '0';
            app.J5 = uilabel(app.ManualMovementPanel);
            app.J5.Text = '0';
            app.J6 = uilabel(app.ManualMovementPanel);
            app.J6.Text = '0';
            app.nJ1Button = uibutton(app.ManualMovementPanel, 'push');
            app.nJ1Button.ButtonPushedFcn = createCallbackFcn(app, @nJ1ButtonPushed, true);
            app.nJ1Button.Text = '<';
            app.nJ2Button = uibutton(app.ManualMovementPanel, 'push');
            app.nJ2Button.ButtonPushedFcn = createCallbackFcn(app, @nJ2ButtonPushed, true);
            app.nJ2Button.Text = '<';
            app.nJ3Button = uibutton(app.ManualMovementPanel, 'push');
            app.nJ3Button.ButtonPushedFcn = createCallbackFcn(app, @nJ3ButtonPushed, true);
            app.nJ3Button.Text = '<';
            app.nJ4Button = uibutton(app.ManualMovementPanel, 'push');
            app.nJ4Button.ButtonPushedFcn = createCallbackFcn(app, @nJ4ButtonPushed, true);
            app.nJ4Button.Text = '<';
            app.nJ5Button = uibutton(app.ManualMovementPanel, 'push');
            app.nJ5Button.ButtonPushedFcn = createCallbackFcn(app, @nJ5ButtonPushed, true);
            app.nJ5Button.Text = '<';
            app.nJ6Button = uibutton(app.ManualMovementPanel, 'push');
            app.nJ6Button.ButtonPushedFcn = createCallbackFcn(app, @nJ6ButtonPushed, true);
            app.nJ6Button.Text = '<';
            app.pJ1Button = uibutton(app.ManualMovementPanel, 'push');
            app.pJ1Button.ButtonPushedFcn = createCallbackFcn(app, @pJ1ButtonPushed, true);
            app.pJ1Button.Text = '>';
            app.pJ2Button = uibutton(app.ManualMovementPanel, 'push');
            app.pJ2Button.ButtonPushedFcn = createCallbackFcn(app, @pJ2ButtonPushed, true);
            app.pJ2Button.Text = '>';
            app.pJ3Button = uibutton(app.ManualMovementPanel, 'push');
            app.pJ3Button.ButtonPushedFcn = createCallbackFcn(app, @pJ3ButtonPushed, true);
            app.pJ3Button.Text = '>';
            app.pJ4Button = uibutton(app.ManualMovementPanel, 'push');
            app.pJ4Button.ButtonPushedFcn = createCallbackFcn(app, @pJ4ButtonPushed, true);
            app.pJ4Button.Text = '>';
            app.pJ5Button = uibutton(app.ManualMovementPanel, 'push');
            app.pJ5Button.ButtonPushedFcn = createCallbackFcn(app, @pJ5ButtonPushed, true);
            app.pJ5Button.Text = '>';
            app.pJ6Button = uibutton(app.ManualMovementPanel, 'push');
            app.pJ6Button.ButtonPushedFcn = createCallbackFcn(app, @pJ6ButtonPushed, true);
            app.pJ6Button.Text = '>';
            app.RobotDropDownLabel = uilabel(app.ManualMovementPanel);
            app.RobotDropDownLabel.Text = 'Robot:';
            app.RobotDropDown = uidropdown(app.ManualMovementPanel);
            app.RobotDropDown.Items = {'Laserbot', 'TargetBot'};
            app.RobotDropDown.ValueChangedFcn = createCallbackFcn(app, @RobotDropDownValueChanged, true);
            app.RobotDropDown.Value = 'Laserbot';
            app.SimulationProgramPanel = uipanel(app.UIFigure);
            app.SimulationProgramPanel.Title = 'Simulation Program';
            app.InitialiseButton = uibutton(app.SimulationProgramPanel, 'push');
            app.InitialiseButton.ButtonPushedFcn = createCallbackFcn(app, @InitialiseButtonPushed, true);
            app.InitialiseButton.Text = 'Initialise';
            app.RandomTargetPracticeButton = uibutton(app.SimulationProgramPanel, 'push');
            app.RandomTargetPracticeButton.ButtonPushedFcn = createCallbackFcn(app, @RandomTargetPracticeButtonPushed, true);
            app.RandomTargetPracticeButton.Text = 'Random Target Practice';
            app.CreateHazardSignButton = uibutton(app.SimulationProgramPanel, 'push');
            app.CreateHazardSignButton.ButtonPushedFcn = createCallbackFcn(app, @CreateHazardSignButtonPushed, true);
            app.CreateHazardSignButton.Text = 'Create Hazard Sign';
            app.RemoveHazardSignButton = uibutton(app.SimulationProgramPanel, 'push');
            app.RemoveHazardSignButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveHazardSignButtonPushed, true);
            app.RemoveHazardSignButton.Text = 'Remove Hazard Sign';
            app.CreateObstacleButton = uibutton(app.SimulationProgramPanel, 'push');
            app.CreateObstacleButton.ButtonPushedFcn = createCallbackFcn(app, @CreateObstacleButtonPushed, true);
            app.CreateObstacleButton.Text = 'Create Obstacle';
            app.RemoveObstacleButton = uibutton(app.SimulationProgramPanel, 'push');
            app.RemoveObstacleButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveObstacleButtonPushed, true);
            app.RemoveObstacleButton.Text = 'Remove Obstacle';
            app.LaserbotFindTargetButton = uibutton(app.SimulationProgramPanel, 'push');
            app.LaserbotFindTargetButton.ButtonPushedFcn = createCallbackFcn(app, @LaserbotFindTargetButtonPushed, true);
            app.LaserbotFindTargetButton.Text = 'Laserbot: Find Target';
            app.TargetbotSetRandomTargetLocationButton = uibutton(app.SimulationProgramPanel, 'push');
            app.TargetbotSetRandomTargetLocationButton.ButtonPushedFcn = createCallbackFcn(app, @TargetbotSetRandomTargetLocationButtonPushed, true);
            app.TargetbotSetRandomTargetLocationButton.Text = 'Targetbot: Set Random Target Location';
            app.MoveDennis = uibutton(app.SimulationProgramPanel, 'push');
            app.MoveDennis.ButtonPushedFcn = createCallbackFcn(app, @MoveDennisButtonPushed, true);
            app.MoveDennis.Text = 'Move Dennis';
            app.EMERGENCYSTOPLabel = uilabel(app.UIFigure);
            app.EMERGENCYSTOPLabel.Text = 'EMERGENCY STOP';
            app.EmergencyStop = uiswitch(app.UIFigure, 'slider');
            app.EmergencyStop.ValueChangedFcn = createCallbackFcn(app, @EmergencyStopValueChanged, true);
            app.ESTOPLamp = uilamp(app.UIFigure);
            app.ResumeButton = uibutton(app.UIFigure, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.ResumeButton.Text = 'Resume';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUITesting

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end